classdef simpson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IntegralEditField        matlab.ui.control.NumericEditField
        IntegralEditFieldLabel   matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        fxEditField              matlab.ui.control.EditField
        fxEditFieldLabel         matlab.ui.control.Label
        xEditField               matlab.ui.control.EditField
        xEditFieldLabel          matlab.ui.control.Label
        nvalueEditField          matlab.ui.control.NumericEditField
        nvalueEditFieldLabel     matlab.ui.control.Label
        hvalueEditField          matlab.ui.control.NumericEditField
        hvalueEditFieldLabel     matlab.ui.control.Label
        SelectMethodButtonGroup  matlab.ui.container.ButtonGroup
        SimpsonButton            matlab.ui.control.RadioButton
        TrapezoidalButton        matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
        function res = func(app)
            n = app.nvalueEditField.Value;
            h = app.hvalueEditField.Value;
            t = textscan(app.xEditField.Value, '%f', 'Delimiter', ',');
            x = t{1};
            temp = textscan(app.fxEditField.Value, '%f', 'Delimiter', ',');
            fx = temp{1};
            res = simp(fx,x,n,h);
        end
        function res2 = func2(app)
            n = app.nvalueEditField.Value;
            h = app.hvalueEditField.Value;
            t = textscan(app.xEditField.Value, '%f', 'Delimiter', ',');
            x = t{1};
            temp = textscan(app.fxEditField.Value, '%f', 'Delimiter', ',');
            fx = temp{1};
            res2 = trap(fx,x,n,h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: IntegralEditField, RunButton, 
        % SelectMethodButtonGroup
        function RunButtonPushed(app, event)
            if(app.SelectMethodButtonGroup.SelectedObject == app.SimpsonButton)
                app.IntegralEditField.Value = func(app);
            
            else
                app.IntegralEditField.Value = func2(app);
            end
        end

        % Selection changed function: SelectMethodButtonGroup
        function SelectMethodButtonGroupSelectionChanged(app, event)
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectMethodButtonGroupSelectionChanged, true);
            app.SelectMethodButtonGroup.Title = 'Select Method';
            app.SelectMethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.TrapezoidalButton = uiradiobutton(app.SelectMethodButtonGroup);
            app.TrapezoidalButton.Text = 'Trapezoidal';
            app.TrapezoidalButton.Value = true;
            app.SimpsonButton = uiradiobutton(app.SelectMethodButtonGroup);
            app.SimpsonButton.Text = 'Simpson';
            app.hvalueEditFieldLabel = uilabel(app.UIFigure);
            app.hvalueEditFieldLabel.Text = 'h value';
            app.hvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.nvalueEditFieldLabel = uilabel(app.UIFigure);
            app.nvalueEditFieldLabel.Text = 'n value';
            app.nvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.IntegralEditFieldLabel = uilabel(app.UIFigure);
            app.IntegralEditFieldLabel.Text = 'Integral:';
            app.IntegralEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntegralEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end